classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Image                    matlab.ui.control.Image
        ReadButton               matlab.ui.control.Button
        ExportButton             matlab.ui.control.Button
        FiltersButtonGroup       matlab.ui.container.ButtonGroup
        OriginButton             matlab.ui.control.ToggleButton
        BlackButton              matlab.ui.control.ToggleButton
        OldPhotosButton          matlab.ui.control.ToggleButton
        OneClickPeelButton_2     matlab.ui.control.ToggleButton
        OneClickWhiteningButton  matlab.ui.control.ToggleButton
        ExitButton               matlab.ui.control.Button
    end

    
    properties (Access = private)
        image % Description
    end
    
    methods (Access = private)
        
        function dnImg = denoiseBasedLocalStat(app,img,r,sigma)
            if size(img,3)==1
                img=double(img);
                dnImg=denoiseBasedLocalStat_gray(app,img,r,sigma);
                dnImg=uint8(dnImg);
            end
            if size(img,3)==3
                ycc=rgb2ycbcr(img);
                y=ycc(:,:,1);
                dnY=denoiseBasedLocalStat_gray(app,double(y),r,sigma);
                ycc(:,:,1)=uint8(dnY);
                dnImg=ycbcr2rgb(ycc);
            end
        end
        function dnImg=denoiseBasedLocalStat_gray(app,img,r,sigma)
            % 基于局部统计信息的灰度图像滤波去噪
            dnImg=zeros(size(img));
            img=double(img);
            paddedImg=padarray(img,[r,r],'symmetric','both');
            [Yim,YYim]=intergalMap(app,paddedImg);
            for i=r+1:size(paddedImg,1)-r
                for j=r+1:size(paddedImg,2)-r
                    rectSum=Yim(i+r+1,j+r+1)+Yim(i-r,j-r)-Yim(i-r,j+r+1)-Yim(i+r+1,j-r);
                    rectSquareSum=YYim(i+r+1,j+r+1)+YYim(i-r,j-r)-YYim(i-r,j+r+1)-YYim(i+r+1,j-r);
                    num=(2*r+1)^2;
                    avg=rectSum/num;
                    var=rectSquareSum/num-avg^2;
                    k=var/(var+sigma^2);
                    dnImg(i-r,j-r)=(1-k)*avg+k*img(i-r,j-r);
                end
            end
        end
        function [Yim,YYim]=intergalMap(app,img)
            % 生成积分图
            paddedImg=padarray(img,[1 1],0,'pre');
            Yim=zeros(size(paddedImg));
            YYim=Yim;
            for i=2:size(paddedImg,1)
                for j=2:size(paddedImg,2)
                    Yim(i,j)=Yim(i,j-1)+Yim(i-1,j)-Yim(i-1,j-1)+paddedImg(i,j);
                    YYim(i,j)=YYim(i,j-1)+YYim(i-1,j)-YYim(i-1,j-1)+paddedImg(i,j)^2;
                end
            end
        end
        
        function mask=faceMask(app,img,r)
            % 人脸掩模
            mask=zeros(size(img,1),size(img,2));
            mask(img(:,:,1)>20 & img(:,:,2)>40 & img(:,:,3)>50)=1;
            mask=myBoxFilt(app,mask,r);
            mask=mask(:,:,ones(1,3));
        end
                     
        function fImg=myBoxFilt(app,img,r)
            % 盒式滤波
            paddedImg=padarray(img,[r r],'symmetric','both');
            [Yim,~]=intergalMap(app,paddedImg);
            fImg=zeros(size(paddedImg,1) - 2*r, size(paddedImg,2)-2*r);
            for i=r+1:size(paddedImg,1)-r
                for j=r+1:size(paddedImg,2)-r
                    rectSum=Yim(i+r+1,j+r+1)+Yim(i-r,j-r)-Yim(i-r,j+r+1)-Yim(i+r+1,j-r);
                    avg=rectSum/(2*r+1)^2;
                    fImg(i-r,j-r)=avg;
                end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: ReadButton
        function ReadButtonPushed(app, event)
            [file,path] = uigetfile({'*.*'});
            abs_path = [path,file];
            app.image = imread(abs_path);
            
            app.Image.ImageSource = app.image;
        end

        % Selection changed function: FiltersButtonGroup
        function FiltersButtonGroupSelectionChanged(app, event)
            selectedButton = app.FiltersButtonGroup.SelectedObject;
            switch selectedButton.Text
                
                case 'Origin'
                    app.Image.ImageSource = app.image;
                
                case 'Black'
                    heibai = repmat(rgb2gray(app.image),1,1,3);
                    app.Image.ImageSource = heibai;
                
                case 'Old Photos'
                    [h, w, k]=size(app.image);
                    
                    R = double(app.image(:,:,1));
                    G = double(app.image(:,:,2));
                    B = double(app.image(:,:,3));

                    rR = R*0.393 + G*0.769 + B*0.198;
                    rG = R*0.349 + G*0.686 + B*0.168;
                    rB = R*0.272 + G*0.534 + B*0.131;

                    randR = rand()*0.5 + 0.5;
                    randG = rand()*0.5 + 0.5;
                    randB = rand()*0.5 + 0.5;

                    imgn = zeros(h,w,k);
                    imgn(:,:,1) = randR*rR + (1-randR)*R;
                    imgn(:,:,2) = randG*rG + (1-randG)*G;
                    imgn(:,:,3) = randB*rB + (1-randB)*B;

                    app.Image.ImageSource = uint8(imgn);
                
                case 'One Click Whitening'
                    R = double(app.image(:,:,1));
                    G = double(app.image(:,:,2));
                    B = double(app.image(:,:,3));
                    R = R + 30;
                    G = G + 30;
                    B = B + 30;
        
                    imgn = zeros(size(app.image));
                    imgn(:,:,1) = R;
                    imgn(:,:,2) = G;
                    imgn(:,:,3) = B;
                    app.Image.ImageSource = uint8(imgn);

                case 'One Click Peel'
                    noisyImg=app.image;
                    r=30;
                    level=5;
                    sigma=10+level^2;
                    dnImg=denoiseBasedLocalStat(app,noisyImg,r,sigma);
%                     figure,imshow(noisyImg);
%                     figure,imshow(dnImg);
                    mask=faceMask(app,noisyImg,5);
%                     figure,imshow(mask);
                    w=0.5;
                    mergedImg=uint8(double(noisyImg).*(1-w*mask)+w*double(dnImg).*mask);
                    app.Image.ImageSource=uint8(mergedImg);               

            end
        end

        % Callback function
        function EndButtonValueChanged(app, event)
            close all
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
            [file,path] = uiputfile({'*.png'});
            abs_path = [path,file];
            imwrite(app.Image.ImageSource, abs_path);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            button=questdlg('你确定退出吗？','退出软件','是','否','是'); %内容，标题，选项，默认选项
            if strcmp(button,'是')
                  delete(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.ReadButton = uibutton(app.UIFigure, 'push');
            app.ReadButton.ButtonPushedFcn = createCallbackFcn(app, @ReadButtonPushed, true);
            app.ReadButton.Text = 'Read';
            app.ExportButton = uibutton(app.UIFigure, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Text = 'Export';
            app.FiltersButtonGroup = uibuttongroup(app.UIFigure);
            app.FiltersButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FiltersButtonGroupSelectionChanged, true);
            app.FiltersButtonGroup.Title = 'Filters';
            app.OriginButton = uitogglebutton(app.FiltersButtonGroup);
            app.OriginButton.Text = 'Origin';
            app.OriginButton.Value = true;
            app.BlackButton = uitogglebutton(app.FiltersButtonGroup);
            app.BlackButton.Text = 'Black';
            app.OldPhotosButton = uitogglebutton(app.FiltersButtonGroup);
            app.OldPhotosButton.Text = 'Old Photos';
            app.OneClickPeelButton_2 = uitogglebutton(app.FiltersButtonGroup);
            app.OneClickPeelButton_2.Text = 'One Click Peel';
            app.OneClickWhiteningButton = uitogglebutton(app.FiltersButtonGroup);
            app.OneClickWhiteningButton.Text = 'One Click Whitening';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end